classdef matlabui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LogArea              matlab.ui.control.TextArea
        TabGroup             matlab.ui.container.TabGroup
        SenderTab            matlab.ui.container.Tab
        StegoImageLabel      matlab.ui.control.Label
        SecretImageLabel     matlab.ui.control.Label
        CoverImageLabel      matlab.ui.control.Label
        SecretFileLabel      matlab.ui.control.Label
        CoverFileLabel       matlab.ui.control.Label
        PINEditField         matlab.ui.control.EditField
        PINEditFieldLabel    matlab.ui.control.Label
        ResetButton          matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        RunButton            matlab.ui.control.Button
        BrowseSecretButton   matlab.ui.control.Button
        BrowseCoverButton    matlab.ui.control.Button
        StegoAxes            matlab.ui.control.UIAxes
        SecretAxes           matlab.ui.control.UIAxes
        CoverAxes            matlab.ui.control.UIAxes
        ReceiverTab          matlab.ui.container.Tab
        StegoFileLabel_2     matlab.ui.control.Label
        RecoveredImageLabel  matlab.ui.control.Label
        ReceivedImageLabel   matlab.ui.control.Label
        ReceiverResetButton  matlab.ui.control.Button
        ReceiverSaveButton   matlab.ui.control.Button
        ExtractButton        matlab.ui.control.Button
        ReceiverPIN          matlab.ui.control.EditField
        ReceiverPINLabel     matlab.ui.control.Label
        StegoFileLabel       matlab.ui.control.Label
        BrowseStegoButton    matlab.ui.control.Button
        RecoveredAxes        matlab.ui.control.UIAxes
        ReceivedStegoAxes    matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CoverImage
        SecretImage
        StegoImage
        ReceivedStegoImage
        RecoveredSecret

    end
    
    methods (Access = private)
        
        function writeLog(app, message)
        
            oldLog = app.LogArea.Value;
        
            if ischar(oldLog)
                oldLog = {oldLog};
            end
        
            app.LogArea.Value = [oldLog; {message}];
        
            drawnow;
        
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseCoverButton
        function BrowseCoverButtonPushed(app, event)


                [file, path] = uigetfile( ...
                    {'*.jpg;*.jpeg;*.png;*.bmp','Image Files'}, ...
                    'Select Cover Image');

                if isequal(file,0)
                    return;
                end

                img = imread(fullfile(path,file));

                app.CoverImage = img;

                imshow(app.CoverImage,'Parent',app.CoverAxes);


                app.CoverFileLabel.Text = file;

                app.writeLog(['Cover image selected: ' file]);


        end

        % Button pushed function: BrowseSecretButton
        function BrowseSecretButtonPushed(app, event)


                [file, path] = uigetfile( ...
                    {'*.jpg;*.jpeg;*.png;*.bmp','Image Files'}, ...
                    'Select Secret Image');

                if isequal(file,0)
                    return;
                end

                img = imread(fullfile(path,file));

                app.SecretImage = img;

                imshow(app.SecretImage,'Parent',app.SecretAxes);


                app.SecretFileLabel.Text = file;

                app.writeLog(['Secret image selected: ' file]);


        end

        % Value changed function: PINEditField
        function PINEditFieldValueChanged(app, event)


            if isempty(app.PINEditField.Value)
                return;
            end
        
            if strlength(app.PINEditField.Value) == 4
                app.writeLog('PIN entered.');
            end


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)


                if isempty(app.CoverImage)
                    return;
                end

                if isempty(app.SecretImage)
                    return;
                end

                if isempty(app.PINEditField.Value)
                    return;
                end

                if strlength(app.PINEditField.Value) ~= 4
                    return;
                end

                app.StegoImage = embedSecret( ...
                    app.CoverImage, ...
                    app.SecretImage, ...
                    app.PINEditField.Value);

                imshow(app.StegoImage,'Parent',app.StegoAxes);

                app.writeLog('DSP embedding completed.');


        end

        % Button down function: CoverAxes
        function CoverAxesButtonDown(app, event)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

                if isempty(app.StegoImage)
                    app.writeLog('No stego image available to save.');
                    return;
                end

                [file, path] = uiputfile( ...
                    {'*.png','PNG Image'; '*.jpg','JPEG Image'}, ...
                    'Save Stego Image');

                if isequal(file,0)
                    return;
                end

                imwrite(app.StegoImage, fullfile(path,file));

                app.writeLog(['Stego image saved: ' file]);

        end

        % Button pushed function: BrowseStegoButton
        function BrowseStegoButtonPushed(app, event)

                [file, path] = uigetfile( ...
                    {'*.jpg;*.jpeg;*.png;*.bmp','Image Files'}, ...
                    'Select Stego Image');

                if isequal(file,0)
                    return;
                end

                img = imread(fullfile(path,file));

                app.ReceivedStegoImage = img;

                imshow(app.ReceivedStegoImage,'Parent',app.ReceivedStegoAxes);

                app.StegoFileLabel.Text = file;

                app.writeLog(['Stego image received: ' file]);


        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)


            if isempty(app.ReceivedStegoImage)
                return;
            end
        
            if isempty(app.ReceiverPIN.Value)
                return;
            end
        
            if strlength(app.ReceiverPIN.Value) ~= 4
                return;
            end
        
            app.RecoveredSecret = extractSecret( ...
                app.ReceivedStegoImage, ...
                app.ReceiverPIN.Value);
        
            imshow(app.RecoveredSecret,'Parent',app.RecoveredAxes);
        
            app.writeLog('DSP extraction completed.');


        end

        % Button pushed function: ReceiverSaveButton
        function ReceiverSaveButtonPushed(app, event)

                if isempty(app.RecoveredSecret)
                    app.writeLog('No recovered image available to save.');
                    return;
                end

                [file, path] = uiputfile( ...
                    {'*.png','PNG Image'; '*.jpg','JPEG Image'}, ...
                    'Save Recovered Image');

                if isequal(file,0)
                    return;
                end

                imwrite(app.RecoveredSecret, fullfile(path,file));

                app.writeLog(['Recovered image saved: ' file]);

        end

        % Button pushed function: ReceiverResetButton
        function ReceiverResetButtonPushed(app, event)


                app.ReceivedStegoImage = [];
                app.RecoveredSecret = [];

                cla(app.ReceivedStegoAxes);
                cla(app.RecoveredAxes);

                app.StegoFileLabel.Text = 'No stego image selected';
                app.ReceiverPIN.Value = '';

                app.LogArea.Value = {'DSP SECURE IMAGE STEGANOGRAPHY'; 'Ready.'};

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

    
            app.CoverImage = [];
            app.SecretImage = [];
            app.StegoImage = [];
        
            cla(app.CoverAxes);
            cla(app.SecretAxes);
            cla(app.StegoAxes);
        
            app.CoverFileLabel.Text = 'No cover selected';
            app.SecretFileLabel.Text = 'No secret selected';
        
            app.PINEditField.Value = '';
        
            app.LogArea.Value = { ...
                'DSP SECURE IMAGE STEGANOGRAPHY'; ...
                'Ready.'};

        end

        % Value changed function: ReceiverPIN
        function ReceiverPINValueChanged(app, event)


    if isempty(app.ReceiverPIN.Value)
        return;
    end

    if strlength(app.ReceiverPIN.Value) == 4
        app.writeLog('PIN entered.');
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SenderTab = uitab(app.TabGroup);
            app.SenderTab.Title = 'Sender';
            app.CoverAxes = uiaxes(app.SenderTab);
            app.CoverAxes.XTick = [];
            app.CoverAxes.YTick = [];
            app.CoverAxes.ButtonDownFcn = createCallbackFcn(app, @CoverAxesButtonDown, true);
            app.SecretAxes = uiaxes(app.SenderTab);
            app.SecretAxes.XTick = [];
            app.SecretAxes.YTick = [];
            app.StegoAxes = uiaxes(app.SenderTab);
            app.StegoAxes.XTick = [];
            app.StegoAxes.YTick = [];
            app.BrowseCoverButton = uibutton(app.SenderTab, 'push');
            app.BrowseCoverButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCoverButtonPushed, true);
            app.BrowseCoverButton.Text = 'Browse Cover';
            app.BrowseSecretButton = uibutton(app.SenderTab, 'push');
            app.BrowseSecretButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSecretButtonPushed, true);
            app.BrowseSecretButton.Text = 'Browse Secret';
            app.RunButton = uibutton(app.SenderTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SaveButton = uibutton(app.SenderTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.SenderTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PINEditFieldLabel = uilabel(app.SenderTab);
            app.PINEditFieldLabel.Text = 'PIN';
            app.PINEditField = uieditfield(app.SenderTab, 'text');
            app.PINEditField.InputType = 'digits';
            app.PINEditField.ValueChangedFcn = createCallbackFcn(app, @PINEditFieldValueChanged, true);
            app.PINEditField.Placeholder = 'Enter PIN';
            app.CoverFileLabel = uilabel(app.SenderTab);
            app.CoverFileLabel.Text = 'No cover selected';
            app.SecretFileLabel = uilabel(app.SenderTab);
            app.SecretFileLabel.Text = 'No secret selected';
            app.CoverImageLabel = uilabel(app.SenderTab);
            app.CoverImageLabel.Text = 'Cover Image';
            app.SecretImageLabel = uilabel(app.SenderTab);
            app.SecretImageLabel.Text = 'Secret Image';
            app.StegoImageLabel = uilabel(app.SenderTab);
            app.StegoImageLabel.Text = 'Stego Image';
            app.ReceiverTab = uitab(app.TabGroup);
            app.ReceiverTab.Title = 'Receiver';
            app.ReceivedStegoAxes = uiaxes(app.ReceiverTab);
            app.ReceivedStegoAxes.XTick = [];
            app.ReceivedStegoAxes.YTick = [];
            app.ReceivedStegoAxes.ZTick = [];
            app.RecoveredAxes = uiaxes(app.ReceiverTab);
            app.RecoveredAxes.XTick = [];
            app.RecoveredAxes.YTick = [];
            app.BrowseStegoButton = uibutton(app.ReceiverTab, 'push');
            app.BrowseStegoButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseStegoButtonPushed, true);
            app.BrowseStegoButton.Text = 'Browse Stego Image';
            app.StegoFileLabel = uilabel(app.ReceiverTab);
            app.StegoFileLabel.Text = 'No stego image selected';
            app.ReceiverPINLabel = uilabel(app.ReceiverTab);
            app.ReceiverPINLabel.Text = 'PIN';
            app.ReceiverPIN = uieditfield(app.ReceiverTab, 'text');
            app.ReceiverPIN.InputType = 'digits';
            app.ReceiverPIN.ValueChangedFcn = createCallbackFcn(app, @ReceiverPINValueChanged, true);
            app.ReceiverPIN.Placeholder = 'Enter PIN';
            app.ExtractButton = uibutton(app.ReceiverTab, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.ReceiverSaveButton = uibutton(app.ReceiverTab, 'push');
            app.ReceiverSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiverSaveButtonPushed, true);
            app.ReceiverSaveButton.Text = 'Save';
            app.ReceiverResetButton = uibutton(app.ReceiverTab, 'push');
            app.ReceiverResetButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiverResetButtonPushed, true);
            app.ReceiverResetButton.Text = 'Reset';
            app.ReceivedImageLabel = uilabel(app.ReceiverTab);
            app.ReceivedImageLabel.Text = 'Received Image';
            app.RecoveredImageLabel = uilabel(app.ReceiverTab);
            app.RecoveredImageLabel.Text = 'Recovered Image';
            app.StegoFileLabel_2 = uilabel(app.ReceiverTab);
            app.StegoFileLabel_2.Text = 'No stego image selected';
            app.LogArea = uitextarea(app.UIFigure);
            app.LogArea.Value = {'DSP SECURE IMAGE STEGANOGRAPHY'; 'Ready.'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.ExtractButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlabui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end